classdef Respiration2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        InitializeTab                 matlab.ui.container.Tab
        Label_2                       matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        WelcomePressInitializeSensorsButtonWhenReadyLabel  matlab.ui.control.Label
        HeartRatebeatsperminuteLabel  matlab.ui.control.Label
        RespirationRatebreathperminuteLabel  matlab.ui.control.Label
        StopLiveReadingButton         matlab.ui.control.Button
        StartLiveReadingButton        matlab.ui.control.Button
        InitializeSensorsButton       matlab.ui.control.Button
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
        SignInTab                     matlab.ui.container.Tab
        SignInLabel                   matlab.ui.control.Label
        Label_3                       matlab.ui.control.Label
        UserNameLabel                 matlab.ui.control.Label
        PreviousUserCheckBox          matlab.ui.control.CheckBox
        UserNameEditField             matlab.ui.control.EditField
        UserNameEditFieldLabel        matlab.ui.control.Label
        NewUserCheckBox               matlab.ui.control.CheckBox
        UserNameDropDown              matlab.ui.control.DropDown
        UserNameDropDownLabel         matlab.ui.control.Label
        SignInButton                  matlab.ui.control.Button
        CalibrationTab                matlab.ui.container.Tab
        BeginCalibrationButton        matlab.ui.control.Button
        BreathCountLabel_2            matlab.ui.control.Label
        BreathCountLabel              matlab.ui.control.Label
        InstructionsLabel             matlab.ui.control.Label
        HRLabel_2                     matlab.ui.control.Label
        StopButton_3                  matlab.ui.control.Button
        CompleteLabel_2               matlab.ui.control.Label
        StatusLabel_2                 matlab.ui.control.Label
        UsernameFieldLabel_2          matlab.ui.control.Label
        UsernameLabel_2               matlab.ui.control.Label
        countNum_2                    matlab.ui.control.Label
        CountLabel_2                  matlab.ui.control.Label
        TimeNum_2                     matlab.ui.control.Label
        TimeLabel_2                   matlab.ui.control.Label
        PauseButton_2                 matlab.ui.control.Button
        CalibrationPractiseButton     matlab.ui.control.Button
        CalibrationLabel              matlab.ui.control.Label
        UIAxes_2                      matlab.ui.control.UIAxes
        Activity1Tab                  matlab.ui.container.Tab
        HRLabel                       matlab.ui.control.Label
        ScoreLabel                    matlab.ui.control.Label
        InstructionsField             matlab.ui.control.Label
        PlayTutorialButton            matlab.ui.control.Button
        DiscardScoreButton            matlab.ui.control.Button
        StopButton_2                  matlab.ui.control.Button
        CompleteLabel                 matlab.ui.control.Label
        StatusLabel                   matlab.ui.control.Label
        TimestampFieldLabel           matlab.ui.control.Label
        TimestampLabel                matlab.ui.control.Label
        UsernameFieldLabel            matlab.ui.control.Label
        UsernameLabel                 matlab.ui.control.Label
        countNum                      matlab.ui.control.Label
        CountLabel                    matlab.ui.control.Label
        TimeNum                       matlab.ui.control.Label
        TimeLabel                     matlab.ui.control.Label
        SaveScoreButton               matlab.ui.control.Button
        PauseButton                   matlab.ui.control.Button
        StartButton_2                 matlab.ui.control.Button
        Activity1Complete5diaphragmaticbreathsLabel  matlab.ui.control.Label
        UIAxes_A1                     matlab.ui.control.UIAxes
        Activity2Tab                  matlab.ui.container.Tab
        InstructionsField_A2          matlab.ui.control.Label
        HRLabel_A2                    matlab.ui.control.Label
        ScoreLabel_A2                 matlab.ui.control.Label
        PlayTutorialButton_A2         matlab.ui.control.Button
        DiscardScoreButton_A2         matlab.ui.control.Button
        StopButton_A2                 matlab.ui.control.Button
        CompleteLabel_A2              matlab.ui.control.Label
        StatusLabel_A2                matlab.ui.control.Label
        TimestampFieldLabel_A2        matlab.ui.control.Label
        TimestampLabel_A2             matlab.ui.control.Label
        UsernameFieldLabel_A2         matlab.ui.control.Label
        UsernameLabel_A2              matlab.ui.control.Label
        countNum_A2                   matlab.ui.control.Label
        CountLabel_A2                 matlab.ui.control.Label
        TimeNum_A2                    matlab.ui.control.Label
        TimeLabel_A2                  matlab.ui.control.Label
        SaveScoreButton_A2            matlab.ui.control.Button
        PauseButton_A2                matlab.ui.control.Button
        StartButton_A2                matlab.ui.control.Button
        Activity2Label                matlab.ui.control.Label
        UIAxes_A2                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        d %DAQ Interface
        dInfo %DAQ info
        fname; %file to store data
        recorded; %saved data
        calibration; %calibration matrix
        threshold; %peak threshold
        username;
        currentUsers;


    end
    
    methods (Access = private)

        function plotAcc(app, src, evt)
            [data, ts, ~]=read(src, src.ScansAvailableFcnCount,"OutputFormat","Matrix");
            yyaxis(app.UIAxes2,'left');

            % Identifying Data
            % Legend: CB = Chest Band, AB = Abdomen Band, PPG
            PPG_data = data(:, 1);
            CB_data = data(:, 2);
            AB_data = data(:, 2);
            
            % Respiration Data
            % Filtering Data
            % Low pass filter the data with a Butterworth filter
            fs = 100e3;  % Sampling rate
            wn = 25 / (fs / 2);  % Normalized cutoff frequency (for 25 Hz), normalized to Nyquist
            [b, a] = butter(1, wn, 'low');  % First-order Butterworth filter design
            filtered_CB = filtfilt(b, a, CB_data); 
            filtered_AB = filtfilt(b, a, AB_data);
            % Plot
            plot(app.UIAxes, ts, filtered_CB)
            hold on 
            plot(app.UIAxes, ts, filtered_AB)
            title('Live Band Measurements')
            legend(app.UIAxes, ' Chest Band','Abdomen Band');
            hold off
            % Calculating Respiration Rate
            % Finding peaks of filtered data
            thres = 3 * std(filtered_CB); 
            [pks, pkids] = findpeaks(filtered_CB, 'MinPeakProminence', thres, 'MinPeakWidth', 100);
        
            % Computing average heart rate
            rr_int = diff(ts(pkids));  
            RR = 60 / mean(rr_int);  

            app.Label.Text = num2str(RR);           
            
            % PPG Data
            % Filtering Data
            % Low pass filter the data with a Butterworth filter
            fs = 100e3;  % Sampling rate
            wn = 25 / (fs / 2);  % Normalized cutoff frequency (for 25 Hz), normalized to Nyquist
            [b, a] = butter(1, wn, 'low');  % First-order Butterworth filter design
            filtered_PPG = filtfilt(b, a, PPG_data); 
            % Plot
            plot(app.UIAxes2, ts, filtered_PPG);
            hold on
            title('Live PPG Measurements')
            % Calculating Heart Rate
            % Finding peaks of filtered data
            thres = 3 * std(filtered_PPG); 
            [pks, pkids] = findpeaks(filtered_PPG, 'MinPeakProminence', thres, 'MinPeakWidth', 100);
        
            % Computing average heart rate
            rr_int = diff(ts(pkids));  
            BPM = 60 / mean(rr_int);  

            app.Label.Text = num2str(BPM);

        end


        function growCircle(app,thr_circle)
            
            hold(app.UIAxes_A1,'on');
            inc=[.75:0.01:2.25];
            d_color=1/length(inc);
            rgb_colors=[1:-d_color:d_color; d_color:d_color:1; zeros(1,length(inc))];
            t_pause=exp(linspace(log(0.00005), log(0.3),length(inc)));
            for i=1:length(inc)
                axis(app.UIAxes_A1,[0 6 0 6]);

                if(thr_circle)
                    viscircles(app.UIAxes_A1,[3 3],1.9,'Color',rgb_colors(:,find(inc==1.9)),'LineStyle','--');
                    if(i==find(inc==1.9)-1)
                        app.countNum.Text=num2str(1);
                    end
                end
                
                viscircles(app.UIAxes_A1,[3 3],inc(i),'Color',rgb_colors(:,i));
                
                hold(app.UIAxes_A1,'off');
                pause(t_pause(i));
            end
        end

        function shrinkCircle(app,thr_circle)
          
            hold(app.UIAxes_A1,'on');
            inc=[2.25:-0.01:.75];
            d_color=1/length(inc);
            rgb_colors=[d_color:d_color:1; 1:-d_color:d_color; zeros(1,length(inc))];
            t_pause=exp(linspace(log(0.0000001), log(0.0001),length(inc)));
            for i=1:length(inc)
                axis(app.UIAxes_A1,[0 6 0 6]);

                if(thr_circle)
                    viscircles(app.UIAxes_A1,[3 3],1.9,'Color',rgb_colors(:,find(inc==1.9)),'LineStyle','--');
                end
                
                viscircles(app.UIAxes_A1,[3 3],inc(i),'Color',rgb_colors(:,i));
                
                hold(app.UIAxes_A1,'off');
                pause(t_pause(i));
            end
        end

        function grow_shrinkCircle(app)
           
            inc=[2.25:-0.01:.75];
            d_color=1/length(inc);
            rgb_colors=[d_color:d_color:1; 1:-d_color:d_color; zeros(1,length(inc))];

            viscircles(app.UIAxes_A1,[3 3],1.9,'Color',rgb_colors(:,find(inc==1.9)),'LineStyle','--');
            pause(3);
            hold(app.UIAxes_A1,'on');
            growCircle(app,1);
            shrinkCircle(app,1);
        end

        function grow_shrinkCircle2(app)
           
            inc=[2.25:-0.01:.75];
            d_color=1/length(inc);
            rgb_colors=[d_color:d_color:1; 1:-d_color:d_color; zeros(1,length(inc))];
            
            viscircles(app.UIAxes_A1,[3 3],1.9,'Color',rgb_colors(:,find(inc==1.9)),'LineStyle','--');
            pause(3);
            
            app.TimeNum_A2.Text='0:30';

            for i=1:30
                tStart = cputime;
                app.TimeNum_A2.Text=append('0:',num2str(31-i));
                hold(app.UIAxes_A1,'on');
                if(rem(i,5)==0)
                    growCircle(app,1);
                    shrinkCircle(app,1);
                end
                tEnd = cputime - tStart;
                pause(1-tEnd);
              
            end
        end


        function [RR, HR] = calculations(app)
            % Call the data
            [data, ts, ~]=read(src, src.ScansAvailableFcnCount,"OutputFormat","Matrix");
            
            % Identifying Data
            % Legend: CB = Chest Band, AB = Abdomen Band, PPG
            PPG_data = data(:, 1);
            CB_data = data(:, 2);
            AB_data = data(:, 2);
            % Calculate RR - Respiration Rate

            % Calculate HR - Heart Rate

            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function preLoad(app)

            % yyaxis(app.UIAxes,'left');
            % ylabel(app.UIAxes, 'X,Y Acc(G)');

        end

        % Button pushed function: InitializeSensorsButton
        function initDAQ(app, event)
            % mydaq=daq("adi"); %create DAQ Object called mydaq
            addinput(mydaq,'smu1','a','Voltage'); %Add chA as the input - PPG
            addinput(mydaq,'smu1','b','Voltage'); %Add chB as the input - Band 1
            addinput(mydaq,'smu1','c','Voltage'); %Add chB as the input - Band 2
            mydaq.ScansAvailableFcnCount=2*mydaq.Rate; %set samples aquired to 0.5s &HA:changed it to 2 seconds 
            app.d=mydaq; %store DAQ object to app property d
            app.dInfo=mydaq.Channels.Device; %store DAQ properties to app property dInfo

            % Announcement(app,'DAQ initialized')
            app.WelcomePressInitializeSensorsButtonWhenReadyLabel.Position = [21,397,592,55];
            % Set the announcement text
            app.WelcomePressInitializeSensorsButtonWhenReadyLabel.Text = 'Sensors are connected, press the - Start Live Reading - Button when ready';
            % Optional: Adjust style if needed
            app.WelcomePressInitializeSensorsButtonWhenReadyLabel.FontSize = 12;
            app.WelcomePressInitializeSensorsButtonWhenReadyLabel.FontColor = [0, 0, 0]; % Black
        end

        % Button pushed function: StartLiveReadingButton
        function startDAQ(app, event)
            %call plotTemp when enough scans are available
            %Aqcuiring and plotting
            % create callback function to call plotPPG every time 2s scans available
            app.d.ScansAvailableFcn = @(src,evt) plotAcc(app,src,evt);    
            % start acquiring and plotting
             start(app.d, "continuous");
            % disp('DAQ started');
            % Announcement(app,'DAQ initialized')
            app.WelcomePressInitializeSensorsButtonWhenReadyLabel.Position = [21,397,592,55];
            % Set the announcement text
            app.WelcomePressInitializeSensorsButtonWhenReadyLabel.Text = 'When you recieve consistent reading press the - Stop Live Reading- button ';
            % Optional: Adjust style if needed
            app.WelcomePressInitializeSensorsButtonWhenReadyLabel.FontSize = 12;
            app.WelcomePressInitializeSensorsButtonWhenReadyLabel.FontColor = [0, 0, 0]; % Black
            

        end

        % Button pushed function: StopLiveReadingButton
        function stopDAQ(app, event)
            %stop DAQ
            %Clear DAQ buffer and axes

            stop(app.d);                      %stop daq
            flush(app.d);                     %clear data in daq buffer
            cla(app.UIAxes);
            disp('DAQ stopped');

            % Announcement(app,'DAQ initialized')
            app.WelcomePressInitializeSensorsButtonWhenReadyLabel.Position = [21,397,592,55];
            % Set the announcement text
            app.WelcomePressInitializeSensorsButtonWhenReadyLabel.Text = 'Go to the next tab - Sign in -';
            % Optional: Adjust style if needed
            app.WelcomePressInitializeSensorsButtonWhenReadyLabel.FontSize = 12;
            app.WelcomePressInitializeSensorsButtonWhenReadyLabel.FontColor = [0, 0, 0]; % Black
        end

        % Callback function
        function saveData(app, event)
            data=app.recorded;
            save(app.FileNameEditField.Value,'data');
        end

        % Callback function
        function startRecord(app, event)
            value = app.RecordButton.Value;
            app.recorded=[];
        end

        % Callback function
        function addCircle(app, event)
            viscircles(app.UIAxes_A1,[3 3],2,'Color','g','LineStyle','--');
            hold(app.UIAxes_A1,'on');
            inc=[0.1:0.01:2.5];
            for i=1:length(inc)
                %cla(app.UIAxes_A1);
                axis(app.UIAxes_A1,[0 6 0 6]);
                viscircles(app.UIAxes_A1,[3 3],inc(i));
                viscircles(app.UIAxes_A1,[3 3],2,'Color','g','LineStyle','--');
                hold(app.UIAxes_A1,'off');
                pause(0.01);
            end
        end

        % Button down function: Activity1Tab
        function initActivity1(app, event)
            cla(app.UIAxes_A1);
            axis(app.UIAxes_A1,[0 6 0 6]);
            % viscircles(app.UIAxes_A1,[3 3],2,'Color','g','LineStyle','--');
            hold(app.UIAxes_A1,'on');
            app.CountLabel.Text='Count:';
            app.countNum.Text='0';
            app.TimeLabel.Text='Time Remaining:';
            app.TimeNum.Text='2:00';
            
        end

        % Button pushed function: PlayTutorialButton
        function startTutorial(app, event)
            %The red circle represents your breathing
            app.CountLabel.Text='';
            app.countNum.Text='';
            app.TimeLabel.Text='';
            app.TimeNum.Text='';
            app.InstructionsField.Text='The red circle represents your breathing.';
            viscircles(app.UIAxes_A1,[3 3],.75,'Color','r');
            pause(3);
            %When you breathe in, the circle will grow
            app.InstructionsField.Text='When you breathe in, the circle will grow.';
            pause(3);
            growCircle(app,0);
           
            %When you breathe out, the circle will shrink
            app.InstructionsField.Text='When you breathe out, the circle will shrink.';
            pause(3);
            shrinkCircle(app,0);
            
            %The dashed green circle is your goal. Every breath that
            %crosses this threshold will be counted as one breath.
            app.CountLabel.Text='Count:';
            app.countNum.Text='0';
            app.InstructionsField.Text='The dashed green circle is your goal. Every inhale that crosses this threshold will be counted as one breath.';
            

            grow_shrinkCircle(app);

            pause(1);
            %You have 2mins to reach 5 successful diaphragmatic breaths

            app.TimeLabel.Text='Time Remaining:';
            app.TimeNum.Text='2:00';
            
            app.InstructionsField.Text='You have 2mins to finish 5 successful diaphragmatic breaths.';
            pause(5);
            
            cla(app.UIAxes_A1);
            app.CountLabel.Text='Count:';
            app.countNum.Text='0';
            app.TimeLabel.Text='Time Remaining:';
            app.TimeNum.Text='2:00';
            app.InstructionsField.Text='';
        
        end

        % Button pushed function: PlayTutorialButton_A2
        function startTutorial2(app, event)

            % %The red circle represents your breathing
            % app.CountLabel.Text='';
            % app.countNum.Text='';
            % app.TimeLabel.Text='';
            % app.TimeNum.Text='';
            % app.InstructionsField.Text='The red circle represents your breathing.';
            % viscircles(app.UIAxes_A1,[3 3],.75,'Color','r');
            % pause(3);
            % %When you breathe in, the circle will grow
            % app.InstructionsField.Text='When you breathe in, the circle will grow.';
            % pause(3);
            % growCircle(app,0);
            % 
            % %When you breathe out, the circle will shrink
            % app.InstructionsField.Text='When you breathe out, the circle will shrink.';
            % pause(3);
            % shrinkCircle(app,0);
            
            %The dashed green circle is your goal. Every breath that
            %crosses this threshold will be counted as one breath.
            app.CountLabel_A2.Text='Count:';
            app.countNum_A2.Text='0';
            app.InstructionsField_A2.Text='The dashed green circle is your goal. Every inhale that crosses this threshold will be counted as one breath.';
            

            pause(1);
            %We will do 3 rounds where you have do diaphagmatic breathing
            %for 30s. You will have 30s of rest between trials.

            app.TimeLabel_A2.Text='Round 1';
            app.TimeNum_A2.Text='0:30';
            
            app.InstructionsField_A2.Text='Diaphragmatic Breathing';
            
            grow_shrinkCircle2(app);
            pause(5);
            cla(app.UIAxes_A2);
            % app.CountLabel.Text='Count:';
            % app.countNum.Text='0';
            % app.TimeLabel.Text='Time Remaining:';
            % app.TimeNum.Text='2:00';
            app.InstructionsField_A2.Text='';
        end

        % Value changed function: NewUserCheckBox, UserNameEditField
        function NewUser(app, event)
            value = app.NewUserCheckBox.Value
            if value == 1
            app.PreviousUserCheckBox.Visible = 'off'
            app.UserNameEditField.Visible = 'on';
            app.UserNameLabel.Visible = 'on';
            username = app.UserNameEditField.Value;
            else
            app.PreviousUserCheckBox.Visible = 'on'
            app.UserNameLabel.Visible = 'off';
            app.UserNameEditField.Visible = 'off';
            end
        end

        % Value changed function: PreviousUserCheckBox
        function ProvideUserName(app, event)
            value = app.PreviousUserCheckBox.Value
            if value == 1
            app.UserNameEditField.Visible = 'off';
            app.UserNameLabel.Visible = 'off';
            app.NewUserCheckBox.Visible = 'off';
            app.UserNameDropDown.Visible = 'on';
            app.UserNameLabel.Visible = 'on';
            username = app.UserNameEditField.Value;
            else
            app.UserNameDropDown.Visible = 'off';
            app.NewUserCheckBox.Visible = 'on';
            app.UserNameLabel.Visible = 'off';
            end 
            
        end

        % Button pushed function: SignInButton
        function SaveUsername(app, event)
        % New User
        if app.NewUserCheckBox.Value == 1
            username = app.UserNameEditField.Value;
            
            % Check if the username is not empty
            if ~isempty(username)
                % Get the current list of users
                if isfile('CurrentUsers.mat')
                    load('CurrentUsers.mat', 'currentUsers');
                else
                    currentUsers = {}; % Initialize as empty if file doesn't exist
                end
                
                % Check if the username already exists in the list
                if ~ismember(username, currentUsers)
                    % Add the new username to the list
                    currentUsers = [currentUsers, {username}];
                    app.UserNameDropDown.Items = currentUsers;
                    
                    % Save the updated user list to a .mat file
                    filename = 'CurrentUsers.mat';
                    save(filename, 'currentUsers');
                    
                    % Optionally, clear the edit field
                    app.UserNameEditField.Value = '';
                    app.Label_3.Text = 'Move on to the - Calibration - tab';
                else
                    % Optional: Display a message if the username already exists
                    app.Label_3.Text = 'Username already exists!';
                end
            else
                % Optional: Display a message if the edit field is empty
                app.Label_3.Text = 'Please enter a username!';
            end
        end
    
        % Previous User
        if app.PreviousUserCheckBox.Value == 1
            if isfile('CurrentUsers.mat')
                load('CurrentUsers.mat', 'currentUsers');
                app.UserNameDropDown.Items = currentUsers;
                
                % Select the username from the dropdown list
                username = app.UserNameDropDown.Value;
                app.Label_3.Text = 'Move on to the - Calibration - tab';
            else
                app.Label_3.Text = 'No Previous Users Available. Select New User.';
            end
        end
        
        end

        % Button pushed function: CalibrationPractiseButton
        function StartCalibration(app, event)
            % Set the username label
            app.UsernameFieldLabel_2.Text = username;

            % Start Graphing the Date 
            plotAcc(app,src,evt);   
            % Instructions at .5 seconds to breath in as deep as possible
            % Have a breathing monitor count down - In 1 - 2 - 3 - 4 - 5 
            % Out 1 - 2 - 3 - 4 - 5 
        end

        % Button down function: CalibrationTab
        function OpenCalibration(app, event)
            % As soon as the calibration tab is opened- data will start to
            % be printed immediatly - 
            % create callback function to call plotPPG every time 2s scans available
            app.d.ScansAvailableFcn = @(src,evt) plotAcc(app,src,evt); 
            app.InstructionsLabel.Text = 'Right Now you are looking at live data. When you are ready, Press practice'
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InitializeTab = uitab(app.TabGroup);
            app.InitializeTab.Title = 'Initialize';
            app.UIAxes = uiaxes(app.InitializeTab);
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Breathing (AU)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.InitializeTab);
            xlabel(app.UIAxes2, 'Time (s)')
            ylabel(app.UIAxes2, 'PPG')
            zlabel(app.UIAxes2, 'Z')
            app.InitializeSensorsButton = uibutton(app.InitializeTab, 'push');
            app.InitializeSensorsButton.ButtonPushedFcn = createCallbackFcn(app, @initDAQ, true);
            app.InitializeSensorsButton.Text = 'Initialize Sensors';
            app.StartLiveReadingButton = uibutton(app.InitializeTab, 'push');
            app.StartLiveReadingButton.ButtonPushedFcn = createCallbackFcn(app, @startDAQ, true);
            app.StartLiveReadingButton.Text = 'Start Live Reading';
            app.StopLiveReadingButton = uibutton(app.InitializeTab, 'push');
            app.StopLiveReadingButton.ButtonPushedFcn = createCallbackFcn(app, @stopDAQ, true);
            app.StopLiveReadingButton.Text = 'Stop Live Reading';
            app.RespirationRatebreathperminuteLabel = uilabel(app.InitializeTab);
            app.RespirationRatebreathperminuteLabel.Text = 'Respiration Rate (breath per minute):';
            app.HeartRatebeatsperminuteLabel = uilabel(app.InitializeTab);
            app.HeartRatebeatsperminuteLabel.Text = 'Heart Rate (beats per minute):';
            app.WelcomePressInitializeSensorsButtonWhenReadyLabel = uilabel(app.InitializeTab);
            app.WelcomePressInitializeSensorsButtonWhenReadyLabel.Tag = 'Announcement ';
            app.WelcomePressInitializeSensorsButtonWhenReadyLabel.Text = 'Welcome, Press '' Initialize Sensors'' Button When Ready';
            app.Label = uilabel(app.InitializeTab);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.InitializeTab);
            app.Label_2.Text = '';
            app.SignInTab = uitab(app.TabGroup);
            app.SignInTab.Title = 'Sign In';
            app.SignInButton = uibutton(app.SignInTab, 'push');
            app.SignInButton.ButtonPushedFcn = createCallbackFcn(app, @SaveUsername, true);
            app.SignInButton.Text = 'Sign In';
            app.UserNameDropDownLabel = uilabel(app.SignInTab);
            app.UserNameDropDownLabel.Visible = 'off';
            app.UserNameDropDownLabel.Text = 'UserName';
            app.UserNameDropDown = uidropdown(app.SignInTab);
            app.UserNameDropDown.Items = {};
            app.UserNameDropDown.Editable = 'on';
            app.UserNameDropDown.Visible = 'off';
            app.UserNameDropDown.Value = {};
            app.NewUserCheckBox = uicheckbox(app.SignInTab);
            app.NewUserCheckBox.ValueChangedFcn = createCallbackFcn(app, @NewUser, true);
            app.NewUserCheckBox.Text = 'New User';
            app.UserNameEditFieldLabel = uilabel(app.SignInTab);
            app.UserNameEditFieldLabel.Visible = 'off';
            app.UserNameEditFieldLabel.Text = 'UserName';
            app.UserNameEditField = uieditfield(app.SignInTab, 'text');
            app.UserNameEditField.ValueChangedFcn = createCallbackFcn(app, @NewUser, true);
            app.UserNameEditField.Visible = 'off';
            app.PreviousUserCheckBox = uicheckbox(app.SignInTab);
            app.PreviousUserCheckBox.ValueChangedFcn = createCallbackFcn(app, @ProvideUserName, true);
            app.PreviousUserCheckBox.Text = 'Previous User ';
            app.UserNameLabel = uilabel(app.SignInTab);
            app.UserNameLabel.Visible = 'off';
            app.UserNameLabel.Text = 'User Name';
            app.Label_3 = uilabel(app.SignInTab);
            app.Label_3.Tag = 'Announcement ';
            app.Label_3.Text = '';
            app.SignInLabel = uilabel(app.SignInTab);
            app.SignInLabel.Text = 'Sign In';
            app.CalibrationTab = uitab(app.TabGroup);
            app.CalibrationTab.Title = 'Calibration';
            app.CalibrationTab.ButtonDownFcn = createCallbackFcn(app, @OpenCalibration, true);
            app.UIAxes_2 = uiaxes(app.CalibrationTab);
            xlabel(app.UIAxes_2, 'Time (s)')
            ylabel(app.UIAxes_2, 'Breathing (AU)')
            zlabel(app.UIAxes_2, 'Z')
            app.CalibrationLabel = uilabel(app.CalibrationTab);
            app.CalibrationLabel.Text = 'Calibration';
            app.CalibrationPractiseButton = uibutton(app.CalibrationTab, 'push');
            app.CalibrationPractiseButton.ButtonPushedFcn = createCallbackFcn(app, @StartCalibration, true);
            app.CalibrationPractiseButton.Text = 'Calibration Practise ';
            app.PauseButton_2 = uibutton(app.CalibrationTab, 'push');
            app.PauseButton_2.Text = 'Pause';
            app.TimeLabel_2 = uilabel(app.CalibrationTab);
            app.TimeLabel_2.Text = '';
            app.TimeNum_2 = uilabel(app.CalibrationTab);
            app.TimeNum_2.Text = '';
            app.CountLabel_2 = uilabel(app.CalibrationTab);
            app.CountLabel_2.Text = '';
            app.countNum_2 = uilabel(app.CalibrationTab);
            app.countNum_2.Text = '';
            app.UsernameLabel_2 = uilabel(app.CalibrationTab);
            app.UsernameLabel_2.Text = 'Username:';
            app.UsernameFieldLabel_2 = uilabel(app.CalibrationTab);
            app.UsernameFieldLabel_2.Text = 'UsernameField';
            app.StatusLabel_2 = uilabel(app.CalibrationTab);
            app.StatusLabel_2.Text = 'Status:';
            app.CompleteLabel_2 = uilabel(app.CalibrationTab);
            app.CompleteLabel_2.Text = 'Complete';
            app.StopButton_3 = uibutton(app.CalibrationTab, 'push');
            app.StopButton_3.Text = 'Stop';
            app.HRLabel_2 = uilabel(app.CalibrationTab);
            app.HRLabel_2.Text = 'HR:';
            app.InstructionsLabel = uilabel(app.CalibrationTab);
            app.InstructionsLabel.Text = 'Instructions';
            app.BreathCountLabel = uilabel(app.CalibrationTab);
            app.BreathCountLabel.Text = 'Breath Count';
            app.BreathCountLabel_2 = uilabel(app.CalibrationTab);
            app.BreathCountLabel_2.Text = 'Breath Count';
            app.BeginCalibrationButton = uibutton(app.CalibrationTab, 'push');
            app.BeginCalibrationButton.Text = 'Begin Calibration';
            app.Activity1Tab = uitab(app.TabGroup);
            app.Activity1Tab.Title = 'Activity 1';
            app.Activity1Tab.ButtonDownFcn = createCallbackFcn(app, @initActivity1, true);
            app.UIAxes_A1 = uiaxes(app.Activity1Tab);
            app.UIAxes_A1.XTick = [];
            app.UIAxes_A1.YTick = [];
            app.UIAxes_A1.ZTick = [];
            app.Activity1Complete5diaphragmaticbreathsLabel = uilabel(app.Activity1Tab);
            app.Activity1Complete5diaphragmaticbreathsLabel.Text = 'Activity 1: Complete 5 diaphragmatic breaths';
            app.StartButton_2 = uibutton(app.Activity1Tab, 'push');
            app.StartButton_2.Text = 'Start';
            app.PauseButton = uibutton(app.Activity1Tab, 'push');
            app.PauseButton.Text = 'Pause';
            app.SaveScoreButton = uibutton(app.Activity1Tab, 'push');
            app.SaveScoreButton.Text = 'Save Score';
            app.TimeLabel = uilabel(app.Activity1Tab);
            app.TimeLabel.Text = '';
            app.TimeNum = uilabel(app.Activity1Tab);
            app.TimeNum.Text = '';
            app.CountLabel = uilabel(app.Activity1Tab);
            app.CountLabel.Text = '';
            app.countNum = uilabel(app.Activity1Tab);
            app.countNum.Text = '';
            app.UsernameLabel = uilabel(app.Activity1Tab);
            app.UsernameLabel.Text = 'Username:';
            app.UsernameFieldLabel = uilabel(app.Activity1Tab);
            app.UsernameFieldLabel.Text = 'UsernameField';
            app.TimestampLabel = uilabel(app.Activity1Tab);
            app.TimestampLabel.Text = 'Timestamp:';
            app.TimestampFieldLabel = uilabel(app.Activity1Tab);
            app.TimestampFieldLabel.Text = 'TimestampField';
            app.StatusLabel = uilabel(app.Activity1Tab);
            app.StatusLabel.Text = 'Status:';
            app.CompleteLabel = uilabel(app.Activity1Tab);
            app.CompleteLabel.Text = 'Complete';
            app.StopButton_2 = uibutton(app.Activity1Tab, 'push');
            app.StopButton_2.Text = 'Stop';
            app.DiscardScoreButton = uibutton(app.Activity1Tab, 'push');
            app.DiscardScoreButton.Text = 'Discard Score';
            app.PlayTutorialButton = uibutton(app.Activity1Tab, 'push');
            app.PlayTutorialButton.ButtonPushedFcn = createCallbackFcn(app, @startTutorial, true);
            app.PlayTutorialButton.Text = 'Play Tutorial';
            app.InstructionsField = uilabel(app.Activity1Tab);
            app.InstructionsField.Text = '';
            app.ScoreLabel = uilabel(app.Activity1Tab);
            app.ScoreLabel.Text = 'Score: ';
            app.HRLabel = uilabel(app.Activity1Tab);
            app.HRLabel.Text = 'HR:';
            app.Activity2Tab = uitab(app.TabGroup);
            app.Activity2Tab.Title = 'Activity 2';
            app.UIAxes_A2 = uiaxes(app.Activity2Tab);
            app.UIAxes_A2.XTick = [];
            app.UIAxes_A2.YTick = [];
            app.UIAxes_A2.ZTick = [];
            app.Activity2Label = uilabel(app.Activity2Tab);
            app.Activity2Label.Text = 'Activity 2: On/Off Circuits';
            app.StartButton_A2 = uibutton(app.Activity2Tab, 'push');
            app.StartButton_A2.Text = 'Start';
            app.PauseButton_A2 = uibutton(app.Activity2Tab, 'push');
            app.PauseButton_A2.Text = 'Pause';
            app.SaveScoreButton_A2 = uibutton(app.Activity2Tab, 'push');
            app.SaveScoreButton_A2.Text = 'Save Score';
            app.TimeLabel_A2 = uilabel(app.Activity2Tab);
            app.TimeLabel_A2.Text = '';
            app.TimeNum_A2 = uilabel(app.Activity2Tab);
            app.TimeNum_A2.Text = '';
            app.CountLabel_A2 = uilabel(app.Activity2Tab);
            app.CountLabel_A2.Text = '';
            app.countNum_A2 = uilabel(app.Activity2Tab);
            app.countNum_A2.Text = '';
            app.UsernameLabel_A2 = uilabel(app.Activity2Tab);
            app.UsernameLabel_A2.Text = 'Username:';
            app.UsernameFieldLabel_A2 = uilabel(app.Activity2Tab);
            app.UsernameFieldLabel_A2.Text = 'UsernameField';
            app.TimestampLabel_A2 = uilabel(app.Activity2Tab);
            app.TimestampLabel_A2.Text = 'Timestamp:';
            app.TimestampFieldLabel_A2 = uilabel(app.Activity2Tab);
            app.TimestampFieldLabel_A2.Text = 'TimestampField';
            app.StatusLabel_A2 = uilabel(app.Activity2Tab);
            app.StatusLabel_A2.Text = 'Status:';
            app.CompleteLabel_A2 = uilabel(app.Activity2Tab);
            app.CompleteLabel_A2.Text = 'Complete';
            app.StopButton_A2 = uibutton(app.Activity2Tab, 'push');
            app.StopButton_A2.Text = 'Stop';
            app.DiscardScoreButton_A2 = uibutton(app.Activity2Tab, 'push');
            app.DiscardScoreButton_A2.Text = 'Discard Score';
            app.PlayTutorialButton_A2 = uibutton(app.Activity2Tab, 'push');
            app.PlayTutorialButton_A2.ButtonPushedFcn = createCallbackFcn(app, @startTutorial2, true);
            app.PlayTutorialButton_A2.Text = 'Play Tutorial';
            app.ScoreLabel_A2 = uilabel(app.Activity2Tab);
            app.ScoreLabel_A2.Text = 'Score: ';
            app.HRLabel_A2 = uilabel(app.Activity2Tab);
            app.HRLabel_A2.Text = 'HR:';
            app.InstructionsField_A2 = uilabel(app.Activity2Tab);
            app.InstructionsField_A2.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Respiration2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @preLoad)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end